classdef CamGenApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SupportedbyNIHAwardR01EB031166Label  matlab.ui.control.Label
        TheRegentsoftheUniversityofMichiganLabel  matlab.ui.control.Label
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        Image                           matlab.ui.control.Image
        DesignVariablesFinalOutputLabel  matlab.ui.control.Label
        DesignVariablesInitialGuessLabel  matlab.ui.control.Label
        PopupFiguresSwitch              matlab.ui.control.Switch
        PopupFiguresSwitchLabel         matlab.ui.control.Label
        SpringMaxEnergyJEditField       matlab.ui.control.NumericEditField
        SpringMaxEnergyJEditFieldLabel  matlab.ui.control.Label
        SpringMaxDeflectionmEditField   matlab.ui.control.NumericEditField
        SpringMaxDeflectionmEditFieldLabel  matlab.ui.control.Label
        SpringFreeLengthmEditField      matlab.ui.control.NumericEditField
        SpringFreeLengthmEditFieldLabel  matlab.ui.control.Label
        SpringStiffnessNmEditField      matlab.ui.control.NumericEditField
        SpringStiffnessNmEditFieldLabel  matlab.ui.control.Label
        ViableCamGeoFoundLamp           matlab.ui.control.Lamp
        ViableCamGeoFoundLampLabel      matlab.ui.control.Label
        RoMMaxradEditField              matlab.ui.control.NumericEditField
        RoMMaxradEditFieldLabel         matlab.ui.control.Label
        RoMMinradEditField              matlab.ui.control.NumericEditField
        RoMMinradEditFieldLabel         matlab.ui.control.Label
        X7EditField                     matlab.ui.control.NumericEditField
        X7EditFieldLabel                matlab.ui.control.Label
        X6EditField                     matlab.ui.control.NumericEditField
        X6EditFieldLabel                matlab.ui.control.Label
        X5EditField                     matlab.ui.control.NumericEditField
        X5EditFieldLabel                matlab.ui.control.Label
        X4EditField                     matlab.ui.control.NumericEditField
        X4EditFieldLabel                matlab.ui.control.Label
        X3EditField                     matlab.ui.control.NumericEditField
        X3EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        CamCurveSaveFileEditField       matlab.ui.control.EditField
        CamCurveSaveFileEditFieldLabel  matlab.ui.control.Label
        AssistFraction4EditField        matlab.ui.control.NumericEditField
        AssistFraction4EditFieldLabel   matlab.ui.control.Label
        TaskWeight4EditField            matlab.ui.control.NumericEditField
        TaskWeight4EditFieldLabel       matlab.ui.control.Label
        Task4DropDown                   matlab.ui.control.DropDown
        Task4DropDownLabel              matlab.ui.control.Label
        AssistFraction3EditField        matlab.ui.control.NumericEditField
        AssistFraction3EditFieldLabel   matlab.ui.control.Label
        TaskWeight3EditField            matlab.ui.control.NumericEditField
        TaskWeight3EditFieldLabel       matlab.ui.control.Label
        Task3DropDown                   matlab.ui.control.DropDown
        Task3DropDownLabel              matlab.ui.control.Label
        X07EditField                    matlab.ui.control.NumericEditField
        X07EditFieldLabel               matlab.ui.control.Label
        X06EditField                    matlab.ui.control.NumericEditField
        X06EditFieldLabel               matlab.ui.control.Label
        X05EditField                    matlab.ui.control.NumericEditField
        X05EditFieldLabel               matlab.ui.control.Label
        X04EditField                    matlab.ui.control.NumericEditField
        X04EditFieldLabel               matlab.ui.control.Label
        X03EditField                    matlab.ui.control.NumericEditField
        X03EditFieldLabel               matlab.ui.control.Label
        X02EditField                    matlab.ui.control.NumericEditField
        X02EditFieldLabel               matlab.ui.control.Label
        X01EditField                    matlab.ui.control.NumericEditField
        X01EditFieldLabel               matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetDropDownLabel            matlab.ui.control.Label
        AssistFraction2EditField        matlab.ui.control.NumericEditField
        AssistFraction2EditFieldLabel   matlab.ui.control.Label
        TaskWeight2EditField            matlab.ui.control.NumericEditField
        TaskWeight2EditFieldLabel       matlab.ui.control.Label
        AssistFraction1EditField        matlab.ui.control.NumericEditField
        AssistFraction1EditFieldLabel   matlab.ui.control.Label
        Task2DropDown                   matlab.ui.control.DropDown
        Task2DropDownLabel              matlab.ui.control.Label
        Task1DropDown                   matlab.ui.control.DropDown
        Task1DropDownLabel              matlab.ui.control.Label
        TaskWeight1EditField            matlab.ui.control.NumericEditField
        TaskWeight1EditFieldLabel       matlab.ui.control.Label
        PopupPlotExoskeletonAssistanceEditField  matlab.ui.control.NumericEditField
        PopupPlotExoskeletonControllerOverallAssistanceFractionLabel  matlab.ui.control.Label
        OptimizeStiffnessButton         matlab.ui.control.Button
        JointDropDown                   matlab.ui.control.DropDown
        JointDropDownLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OptimizeStiffnessButton
        function OptimizeStiffnessButtonPushed(app, event)

            cla(app.UIAxes);
            
            app.ViableCamGeoFoundLamp.Color = [0.50,0.50,0.50];
            drawnow()
            figTF = strcmp(app.PopupFiguresSwitch.Value,'On');
            springparams.Kspring = app.SpringStiffnessNmEditField.Value;
            springparams.L_spring = app.SpringFreeLengthmEditField.Value;
            springparams.compressLimit = app.SpringMaxDeflectionmEditField.Value;
            springparams.MaxEPE_J_ = app.SpringMaxEnergyJEditField.Value;

            run("definecolorset.m");
            inputActuator = 'AK80-9';
            motorparams = getActuatorData(inputActuator);
            multiplotAssistFrac = app.PopupPlotExoskeletonAssistanceEditField.Value;
            X0(1) = app.X01EditField.Value;
            X0(2) = app.X02EditField.Value;
            X0(3) = app.X03EditField.Value;
            X0(4) = app.X04EditField.Value;
            X0(5) = app.X05EditField.Value;
            X0(6) = app.X06EditField.Value;
            X0(7) = app.X07EditField.Value;

            inputJoint = app.JointDropDown.Value;
            tasklist{1} = app.Task1DropDown.Value;
            datasetlist{1} = app.DatasetDropDown.Value;
            assistFractionList(1) = app.AssistFraction1EditField.Value;
            taskweights(1) = app.TaskWeight1EditField.Value;

            if ~strcmp(app.Task2DropDown,'None')
                tasklist{2} = app.Task2DropDown.Value;
                datasetlist{2} = app.DatasetDropDown.Value;
                assistFractionList(2) = app.AssistFraction2EditField.Value;
                taskweights(2) = app.TaskWeight2EditField.Value;
                if ~strcmp(app.Task3DropDown,'None')
                    tasklist{3} = app.Task3DropDown.Value;
                    datasetlist{3} = app.DatasetDropDown.Value;
                    assistFractionList(3) = app.AssistFraction3EditField.Value;
                    taskweights(3) = app.TaskWeight3EditField.Value;
                    if ~strcmp(app.Task4DropDown,'None')
                        tasklist{4} = app.Task4DropDown.Value;
                        datasetlist{4} = app.DatasetDropDown.Value;
                        assistFractionList(4) = app.AssistFraction4EditField.Value;
                        taskweights(4) = app.TaskWeight4EditField.Value;
                    end
                end
            end

            [X, viable, camsurfpoints,thetamin,thetamax] = ...
                Optimizer(inputJoint,tasklist,datasetlist,taskweights,...
                assistFractionList,inputActuator,'A02',...
                X0,multiplotAssistFrac,springparams, figTF);

            thetacirc = 0:pi/100:2*pi;
            xcirc = motorparams.diameter*0.5*.001 * cos(thetacirc);
            ycirc = motorparams.diameter*0.5*.001 * sin(thetacirc);
            hold(app.UIAxes,'on')

            lite = [82 197 216]/255;
%             med = [37 131 190]/255;
            dark = [0 85 81]/255;

            plot(app.UIAxes,xcirc,ycirc,'color',dark,'LineWidth',2)
            plot(app.UIAxes,0,0,'ko')  

            xlabel(app.UIAxes,'X Dim (m)')
            ylabel(app.UIAxes,'Y Dim (m)')

            if viable 
                app.ViableCamGeoFoundLamp.Color = 'g';
                drawnow()
                plot(app.UIAxes,camsurfpoints.X,camsurfpoints.Y,'color',lite,'LineWidth',2)
                app.RoMMaxradEditField.Value = thetamax;
                app.RoMMinradEditField.Value = thetamin;

                camsurfpoints.Z = zeros(length(camsurfpoints.X),1);
                camsurfpoints.mtx = cat(2,camsurfpoints.X,camsurfpoints.Y,camsurfpoints.Z);
                camsurfpoints.mtx = camsurfpoints.mtx*1000; %convert to mm
                writematrix(camsurfpoints.mtx,app.CamCurveSaveFileEditField.Value)
            else
                app.ViableCamGeoFoundLamp.Color = 'r';
                drawnow()
            end
            
            axis(app.UIAxes,"equal")
            app.X1EditField.Value = X(1);
            app.X2EditField.Value = X(2);            
            app.X3EditField.Value = X(3);
            app.X4EditField.Value = X(4);
            app.X5EditField.Value = X(5);
            app.X6EditField.Value = X(6);
            app.X7EditField.Value = X(7);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cam Profile Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.JointDropDownLabel = uilabel(app.UIFigure);
            app.JointDropDownLabel.Text = 'Joint';
            app.JointDropDown = uidropdown(app.UIFigure);
            app.JointDropDown.Items = {'hip', 'knee', 'ankle'};
            app.JointDropDown.Value = 'knee';
            app.OptimizeStiffnessButton = uibutton(app.UIFigure, 'push');
            app.OptimizeStiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeStiffnessButtonPushed, true);
            app.OptimizeStiffnessButton.Text = 'Optimize Stiffness';
            app.PopupPlotExoskeletonControllerOverallAssistanceFractionLabel = uilabel(app.UIFigure);
            app.PopupPlotExoskeletonControllerOverallAssistanceFractionLabel.Text = {'Popup Figure Setting: '; 'Exo Controller Overall '; '(Cam + Motor) '; 'Assistance Fraction'};
            app.PopupPlotExoskeletonAssistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopupPlotExoskeletonAssistanceEditField.Value = 0.25;
            app.TaskWeight1EditFieldLabel = uilabel(app.UIFigure);
            app.TaskWeight1EditFieldLabel.Text = 'Task Weight 1';
            app.TaskWeight1EditField = uieditfield(app.UIFigure, 'numeric');
            app.TaskWeight1EditField.Value = 0.3;
            app.Task1DropDownLabel = uilabel(app.UIFigure);
            app.Task1DropDownLabel.Text = 'Task 1';
            app.Task1DropDown = uidropdown(app.UIFigure);
            app.Task1DropDown.Items = {'squat25lb', 'normalwalk1x2', 'rampascent', 'rampdescent'};
            app.Task1DropDown.Value = 'squat25lb';
            app.Task2DropDownLabel = uilabel(app.UIFigure);
            app.Task2DropDownLabel.Text = 'Task 2';
            app.Task2DropDown = uidropdown(app.UIFigure);
            app.Task2DropDown.Items = {'squat25lb', 'normalwalk1x2', 'rampascent', 'rampdescent', 'None'};
            app.Task2DropDown.Value = 'normalwalk1x2';
            app.AssistFraction1EditFieldLabel = uilabel(app.UIFigure);
            app.AssistFraction1EditFieldLabel.Text = 'Assist Fraction 1';
            app.AssistFraction1EditField = uieditfield(app.UIFigure, 'numeric');
            app.AssistFraction1EditField.Value = 0.08;
            app.TaskWeight2EditFieldLabel = uilabel(app.UIFigure);
            app.TaskWeight2EditFieldLabel.Text = 'Task Weight 2';
            app.TaskWeight2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TaskWeight2EditField.Value = 0.5;
            app.AssistFraction2EditFieldLabel = uilabel(app.UIFigure);
            app.AssistFraction2EditFieldLabel.Text = 'Assist Fraction 2';
            app.AssistFraction2EditField = uieditfield(app.UIFigure, 'numeric');
            app.AssistFraction2EditField.Value = 0.1;
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {'GT2023means_mat', 'Bovi'};
            app.DatasetDropDown.Value = 'GT2023means_mat';
            app.X01EditFieldLabel = uilabel(app.UIFigure);
            app.X01EditFieldLabel.Text = 'X0[1]';
            app.X01EditField = uieditfield(app.UIFigure, 'numeric');
            app.X01EditField.Value = -136;
            app.X02EditFieldLabel = uilabel(app.UIFigure);
            app.X02EditFieldLabel.Text = 'X0[2]';
            app.X02EditField = uieditfield(app.UIFigure, 'numeric');
            app.X02EditField.Value = 57;
            app.X03EditFieldLabel = uilabel(app.UIFigure);
            app.X03EditFieldLabel.Text = 'X0[3]';
            app.X03EditField = uieditfield(app.UIFigure, 'numeric');
            app.X03EditField.Value = 5;
            app.X04EditFieldLabel = uilabel(app.UIFigure);
            app.X04EditFieldLabel.Text = 'X0[4]';
            app.X04EditField = uieditfield(app.UIFigure, 'numeric');
            app.X04EditField.Value = 2.4;
            app.X05EditFieldLabel = uilabel(app.UIFigure);
            app.X05EditFieldLabel.Text = 'X0[5]';
            app.X05EditField = uieditfield(app.UIFigure, 'numeric');
            app.X05EditField.Value = 5.3;
            app.X06EditFieldLabel = uilabel(app.UIFigure);
            app.X06EditFieldLabel.Text = 'X0[6]';
            app.X06EditField = uieditfield(app.UIFigure, 'numeric');
            app.X06EditField.Value = 5.46;
            app.X07EditFieldLabel = uilabel(app.UIFigure);
            app.X07EditFieldLabel.Text = 'X0[7]';
            app.X07EditField = uieditfield(app.UIFigure, 'numeric');
            app.X07EditField.Value = 0.001;
            app.Task3DropDownLabel = uilabel(app.UIFigure);
            app.Task3DropDownLabel.Text = 'Task 3';
            app.Task3DropDown = uidropdown(app.UIFigure);
            app.Task3DropDown.Items = {'squat25lb', 'normalwalk1x2', 'rampascent', 'rampdescent', 'None'};
            app.Task3DropDown.Value = 'rampascent';
            app.TaskWeight3EditFieldLabel = uilabel(app.UIFigure);
            app.TaskWeight3EditFieldLabel.Text = 'Task Weight 3';
            app.TaskWeight3EditField = uieditfield(app.UIFigure, 'numeric');
            app.TaskWeight3EditField.Value = 0.1;
            app.AssistFraction3EditFieldLabel = uilabel(app.UIFigure);
            app.AssistFraction3EditFieldLabel.Text = 'Assist Fraction 3';
            app.AssistFraction3EditField = uieditfield(app.UIFigure, 'numeric');
            app.AssistFraction3EditField.Value = 0.1;
            app.Task4DropDownLabel = uilabel(app.UIFigure);
            app.Task4DropDownLabel.Text = 'Task 4';
            app.Task4DropDown = uidropdown(app.UIFigure);
            app.Task4DropDown.Items = {'squat25lb', 'normalwalk1x2', 'rampascent', 'rampdescent', 'None'};
            app.Task4DropDown.Value = 'rampdescent';
            app.TaskWeight4EditFieldLabel = uilabel(app.UIFigure);
            app.TaskWeight4EditFieldLabel.Text = 'Task Weight 4';
            app.TaskWeight4EditField = uieditfield(app.UIFigure, 'numeric');
            app.TaskWeight4EditField.Value = 0.1;
            app.AssistFraction4EditFieldLabel = uilabel(app.UIFigure);
            app.AssistFraction4EditFieldLabel.Text = 'Assist Fraction 4';
            app.AssistFraction4EditField = uieditfield(app.UIFigure, 'numeric');
            app.AssistFraction4EditField.Value = 0.1;
            app.CamCurveSaveFileEditFieldLabel = uilabel(app.UIFigure);
            app.CamCurveSaveFileEditFieldLabel.Text = 'Cam Curve Save File';
            app.CamCurveSaveFileEditField = uieditfield(app.UIFigure, 'text');
            app.CamCurveSaveFileEditField.Value = 'camXYZ.txt';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X[1]';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X[2]';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X3EditFieldLabel = uilabel(app.UIFigure);
            app.X3EditFieldLabel.Text = 'X[3]';
            app.X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X4EditFieldLabel = uilabel(app.UIFigure);
            app.X4EditFieldLabel.Text = 'X[4]';
            app.X4EditField = uieditfield(app.UIFigure, 'numeric');
            app.X5EditFieldLabel = uilabel(app.UIFigure);
            app.X5EditFieldLabel.Text = 'X[5]';
            app.X5EditField = uieditfield(app.UIFigure, 'numeric');
            app.X6EditFieldLabel = uilabel(app.UIFigure);
            app.X6EditFieldLabel.Text = 'X[6]';
            app.X6EditField = uieditfield(app.UIFigure, 'numeric');
            app.X7EditFieldLabel = uilabel(app.UIFigure);
            app.X7EditFieldLabel.Text = 'X[7]';
            app.X7EditField = uieditfield(app.UIFigure, 'numeric');
            app.RoMMinradEditFieldLabel = uilabel(app.UIFigure);
            app.RoMMinradEditFieldLabel.Text = 'RoM Min. (rad)';
            app.RoMMinradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoMMaxradEditFieldLabel = uilabel(app.UIFigure);
            app.RoMMaxradEditFieldLabel.Text = 'RoM Max. (rad)';
            app.RoMMaxradEditField = uieditfield(app.UIFigure, 'numeric');
            app.ViableCamGeoFoundLampLabel = uilabel(app.UIFigure);
            app.ViableCamGeoFoundLampLabel.Text = 'Viable Cam Geo Found';
            app.ViableCamGeoFoundLamp = uilamp(app.UIFigure);
            app.SpringStiffnessNmEditFieldLabel = uilabel(app.UIFigure);
            app.SpringStiffnessNmEditFieldLabel.Text = 'Spring Stiffness (N/m)';
            app.SpringStiffnessNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringStiffnessNmEditField.Value = 10714.7851609803;
            app.SpringFreeLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.SpringFreeLengthmEditFieldLabel.Text = 'Spring Free Length (m)';
            app.SpringFreeLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringFreeLengthmEditField.Value = 0.0979932;
            app.SpringMaxDeflectionmEditFieldLabel = uilabel(app.UIFigure);
            app.SpringMaxDeflectionmEditFieldLabel.Text = 'Spring Max Deflection (m)';
            app.SpringMaxDeflectionmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringMaxDeflectionmEditField.Value = 0.029718;
            app.SpringMaxEnergyJEditFieldLabel = uilabel(app.UIFigure);
            app.SpringMaxEnergyJEditFieldLabel.Text = 'Spring Max Energy (J)';
            app.SpringMaxEnergyJEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringMaxEnergyJEditField.Value = 4.7314;
            app.PopupFiguresSwitchLabel = uilabel(app.UIFigure);
            app.PopupFiguresSwitchLabel.Text = 'Popup Figures';
            app.PopupFiguresSwitch = uiswitch(app.UIFigure, 'slider');
            app.DesignVariablesInitialGuessLabel = uilabel(app.UIFigure);
            app.DesignVariablesInitialGuessLabel.Text = 'Design Variables: Initial Guess';
            app.DesignVariablesFinalOutputLabel = uilabel(app.UIFigure);
            app.DesignVariablesFinalOutputLabel.Text = 'Design Variables: Final Output';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'robotics-social.png');
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://neurobionics.robotics.umich.edu/';
            app.Hyperlink.Text = 'Neurobionics Lab';
            app.Hyperlink_2 = uihyperlink(app.UIFigure);
            app.Hyperlink_2.URL = 'https://locolab.robotics.umich.edu/';
            app.Hyperlink_2.Text = 'Locomotor Control Systems Lab';
            app.Hyperlink_3 = uihyperlink(app.UIFigure);
            app.Hyperlink_3.URL = 'https://polyformproject.org/licenses/noncommercial/1.0.0/';
            app.Hyperlink_3.Text = 'PolyForm Noncommercial License';
            app.TheRegentsoftheUniversityofMichiganLabel = uilabel(app.UIFigure);
            app.TheRegentsoftheUniversityofMichiganLabel.Text = '© The Regents of the University of Michigan';
            app.SupportedbyNIHAwardR01EB031166Label = uilabel(app.UIFigure);
            app.SupportedbyNIHAwardR01EB031166Label.Text = 'Supported by NIH Award R01EB031166';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CamGenApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end